classdef DetectionSuperviserMaker < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure         matlab.ui.Figure
        GridLayout       matlab.ui.container.GridLayout
        LoadPanel        matlab.ui.container.Panel
        LoadImageButton  matlab.ui.control.Button
        UIAxes           matlab.ui.control.UIAxes
    end

    
    methods (Access = private)
        
        function showImage(app, Img)
            fnc_imshow(app.UIAxes, Img);
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: LoadImageButton
        function LoadImageButtonPushed(app, event)
            [f, p] = uigetfile({'*.tif;*.tiff;*.TIF;*.TIFF', 'tiff image'; ...
                                '*.*', '*.*'}, "Select Image");
            if f == 0
                Img = imread("cell.tif");
                app.showImage(Img);
                r = fnc_drawClickableRectangle(app.UIAxes);
                return;
            end

            Img = imread(fullfile(p, f));
            app.showImage(Img);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.GridLayout = uigridlayout(app.UIFigure);
            app.UIAxes = uiaxes(app.GridLayout);
            app.UIAxes.XTick = [];
            app.UIAxes.YTick = [];
            app.LoadPanel = uipanel(app.GridLayout);
            app.LoadPanel.Title = 'Load';
            app.LoadImageButton = uibutton(app.LoadPanel, 'push');
            app.LoadImageButton.ButtonPushedFcn = createCallbackFcn(app, @LoadImageButtonPushed, true);
            app.LoadImageButton.Text = 'Image';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = DetectionSuperviserMaker

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end